classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SubmitButton            matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        FunctionsDropDownLabel  matlab.ui.control.Label
        FunctionsDropDown       matlab.ui.control.DropDown
        UIAxes3                 matlab.ui.control.UIAxes
        SelectForImage1Button   matlab.ui.control.Button
        SelectForImage2Button   matlab.ui.control.Button
        UIAxes4                 matlab.ui.control.UIAxes
        Number1EditFieldLabel   matlab.ui.control.Label
        Number1EditField        matlab.ui.control.NumericEditField
        BounsDropDownLabel      matlab.ui.control.Label
        BounsDropDown           matlab.ui.control.DropDown
        Button                  matlab.ui.control.Button
        Number2EditFieldLabel   matlab.ui.control.Label
        Number2EditField        matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        %img=imread('image4.jpg')
        img1=0;
        img2=0;
        number=0; % Description
        number2=0;
    end


    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
          
             action = app.FunctionsDropDown.Value;
             
             switch action
                 case 'DM0L'
                     import DM_0L;
                     img=DM_0L(app.img1,app.number);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'DM1L'
                     import DM_1L;
                     img=DM_1L(app.img1, app.number);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'RM0L'
                     import RM0Order;
                     img=RM0Order(app.img1, app.number,app.number2);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'RM1L'
                     import RM1Order;
                     img=RM1Order(app.img1, app.number,app.number2);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'ConvertToGrayScaleSingle'
                     import ConvertToGraySingaleColorChannel;
                     img=ConvertToGraySingaleColorChannel(app.img1);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'ConvertToGrayDecomposing'
                     import ConvertToGrayDecomposing;
                     img=ConvertToGrayDecomposing(app.img1);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'ConvertToGrayScaleAVG'
                     import ConvertToGrayAveraging;
                     img=ConvertToGrayAveraging(app.img1);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'ConvertToGrayScaleLuminance'
                     import ConvertToGrayLuminance;
                     img=ConvertToGrayLuminance(app.img1);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'ConvertToGrayScaleDecomposing'
                     import ConvertToGrayDecomposing;
                     img=ConvertToGrayDecomposing(app.img1);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'ConvertToGrayScaleDesaturation'
                     import ConvertToGrayDesaturation;
                     img=ConvertToGrayDesaturation(app.img1);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'DrawHistogram'
                     import DrawHistogram;
                     freqArray=DrawHistogram(app.img1);
                     stem(app.UIAxes4,freqArray);
                 case 'Brightness'
                     import Brightness;
                     img=Brightness(app.img1, app.number);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'Constrast'
                     import Contrast;
                     img=Contrast(app.img1, app.number,app.number2);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'PowerLawTransformations'
                     import PowerLawTransformations;
                     img=PowerLawTransformations(app.img1, app.number);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'HistogramEqualization'
                     import HistogramEqualization;
                     img=HistogramEqualization(app.img1);
                     imshow(img, 'Parent', app.UIAxes3); 
                 case 'HistogramMatching'
                     import HistogramMatching;
                     img=HistogramMatching(app.img1,app.img2);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'AddImage'
                     import AddImage;
                     img=AddImage(app.img1, app.img2);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'SubtractImage'
                     import SubtractImage;
                     img=SubtractImage(app.img1, app.img2);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'ImageNegative'
                     import Negative;
                     img=Negative(app.img1);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'MeanFilter'
                     import MeanFilter;
                     img=MeanFilter(app.img1,app.number);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'WeightedFilter'
                     import WeightedFilter;
                     img=WeightedFilter(app.img1,app.number);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'MedianFilter'
                     import MedianFilter;
                     img=MedianFilter(app.img1,app.number);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'MaxFilter'
                     import MaxFilter;
                     img=MaxFilter(app.img1,app.number);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'MinFilter'
                     import MinFilter;
                     img=MinFilter(app.img1,app.number);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'EdgeDetection'
                     import EdgeDetection;
                     img=EdgeDetection(app.img1);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'SharpeningFilter'
                     import Sharpening;
                     img=Sharpening(app.img1);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'UnsharpeMasking'
                     import UnsharpeMasking;
                     img=UnsharpeMasking(app.img1);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'Quantization'
                     import Quantization;
                     img=Quantization(app.img1,app.number);
                     imshow(img, 'Parent', app.UIAxes3);   
                     
             end
        end

        % Callback function
        function Button2Pushed(app, event)
            imshow(app.img1, 'Parent',app.UIAxes);
        end

        % Callback function
        function Button3Pushed(app, event)
            mask = [0 1 0;1 4 1;0 1 0];
            import ConvertToGrayLuminance;
            gray = ConvertToGrayLuminance(app.img1);
            % addition padding for image
            image_pad = padarray(gray, [1 1], 'replicate','both');
            
            % size of image after add padding
            [rows,cols] = size(image_pad);


            for x=2:rows-1    % loop rows image
    for y=2:cols-1    % loop columns image
          %value = mask(3,2)*image_pad(x+1,y)+mask(1,2)*image_pad(x-1,y)+mask(2,3)*image_pad(x,y+1)+mask(2,1)*image_pad(x,y-1)-mask(2,2)*image_pad(x,y);
                             
          result=0;  %variable to store the result of operation
            
            i_row = 1;
            for i=rows-1:rows+1  %loop row mask
                j_col=1;
                for j=cols-1:cols+1   %loop row image
                    result = result + (image_pad(x,y)*mask(i_row,j_col));
                    j_col = j_col + 1;
                end
                i_row = i_row + 1;
            end
            
            
        if result>20
           gray(x,y)=255;
        else
            gray(x,y)=0;
        end
    end
            end
            
            output = gray;
            % to display two images in one  figure 
            imshow(output, 'Parent', app.UIAxes3);
        end

        % Value changed function: FunctionsDropDown
        function FunctionsDropDownValueChanged(app, event)
            value = app.FunctionsDropDown.Value;
            
        end

        % Button pushed function: SelectForImage1Button
        function SelectForImage1ButtonPushed(app, event)
    % Create a temporary figure to prevent the main figure from closing
    tmpFig = figure;
    
    % Open the file selection dialog
    [file, path] = uigetfile('*.*');
    
    % Close the temporary figure
    close(tmpFig);
    
    % Check if a file was selected or the dialog was canceled
    if isequal(file, 0)
        % If no file was selected, return without doing anything
        return;
    end
    
    % Load the image and display it in the UIAxes component
    app.img1 = imread(fullfile(path, file));
    imshow(app.img1, 'parent', app.UIAxes);
    
    % Set the DataAspectRatio to resize the image (adjust as needed)
    app.UIAxes.DataAspectRatio = [1 1 1]; % Adjust the values for scaling
        end

        % Button pushed function: SelectForImage2Button
        function SelectForImage2ButtonPushed(app, event)
    % Create a temporary figure to prevent the main figure from closing
    tmpFig = figure;
    
    % Open the file selection dialog
    [file, path] = uigetfile('*.*');
    
    % Close the temporary figure
    close(tmpFig);
    
    % Check if a file was selected or the dialog was canceled
    if isequal(file, 0)
        % If no file was selected, return without doing anything
        return;
    end
    
    % Load the image and display it in the UIAxes2 component
    app.img2 = imread(fullfile(path, file));
    imshow(app.img2, 'parent', app.UIAxes2);
    
    % Set the DataAspectRatio to resize the image (adjust as needed)
    app.UIAxes2.DataAspectRatio = [1 1 1]; % Adjust the values for scaling
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            action = app.BounsDropDown.Value;
            switch action
                 case 'GeometricMeanFilter'
                     import GeometricMeanFilter;
                     img=GeometricMeanFilter(app.img1,app.number);
                     imshow(img, 'Parent', app.UIAxes3);
                 case 'MidPointFilter'
                     import MidPointFilter;
                     img=MidPointFilter(app.img1,app.number);
                     imshow(img, 'Parent', app.UIAxes3);
                case 'Salt&Pepper'
                     import SaltPepper;
                     img=SaltPepper(app.img1,app.number);
                     imshow(img, 'Parent', app.UIAxes3);
                case 'Gaussian'
                     import GaussianNoise;
                     img=GaussianNoise(app.img1);
                     imshow(img, 'Parent', app.UIAxes3);  
            end
        end

        % Value changed function: Number1EditField
        function Number1EditFieldValueChanged(app, event)
            value = app.Number1EditField.Value;
            app.number=value;
        end

        % Value changed function: BounsDropDown
        function BounsDropDownValueChanged(app, event)
            value = app.BounsDropDown.Value;
        end

        % Callback function
        function Number2EditFieldValueChanged(app, event)
            value = app.Number2EditField.Value;
            app.number2=value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input-Image1');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Input-Image2');
            app.FunctionsDropDownLabel = uilabel(app.UIFigure);
            app.FunctionsDropDownLabel.Text = 'Functions';
            app.FunctionsDropDown = uidropdown(app.UIFigure);
            app.FunctionsDropDown.Items = {'DM0L', 'DM1L', 'RM0L', 'RM1L', 'ConvertToGrayScaleSingle', 'ConvertToGrayScaleAVG', 'ConvertToGrayScaleLuminance', 'ConvertToGrayScaleDecomposing', 'ConvertToGrayScaleDesaturation', 'DrawHistogram', 'Brightness', 'Constrast', 'PowerLawTransformations', 'HistogramEqualization', 'HistogramMatching', 'AddImage', 'SubtractImage', 'ImageNegative', 'MeanFilter', 'WeightedFilter', 'MedianFilter', 'MaxFilter', 'MinFilter', 'EdgeDetection', 'SharpeningFilter', 'UnsharpeMasking', 'Quantization'};
            app.FunctionsDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionsDropDownValueChanged, true);
            app.FunctionsDropDown.Value = 'DM0L';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Output-Image');
            app.SelectForImage1Button = uibutton(app.UIFigure, 'push');
            app.SelectForImage1Button.ButtonPushedFcn = createCallbackFcn(app, @SelectForImage1ButtonPushed, true);
            app.SelectForImage1Button.Text = 'Select-For-Image1';
            app.SelectForImage2Button = uibutton(app.UIFigure, 'push');
            app.SelectForImage2Button.ButtonPushedFcn = createCallbackFcn(app, @SelectForImage2ButtonPushed, true);
            app.SelectForImage2Button.Text = 'Select-For-Image2';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Histogram');
            app.Number1EditFieldLabel = uilabel(app.UIFigure);
            app.Number1EditFieldLabel.Text = 'Number1';
            app.Number1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Number1EditField.ValueChangedFcn = createCallbackFcn(app, @Number1EditFieldValueChanged, true);
            app.BounsDropDownLabel = uilabel(app.UIFigure);
            app.BounsDropDownLabel.Text = 'Bouns';
            app.BounsDropDown = uidropdown(app.UIFigure);
            app.BounsDropDown.Items = {'GeometricMeanFilter', 'MidPointFilter', 'Salt&Pepper', 'Gaussian'};
            app.BounsDropDown.ValueChangedFcn = createCallbackFcn(app, @BounsDropDownValueChanged, true);
            app.BounsDropDown.Value = 'GeometricMeanFilter';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Number2EditFieldLabel = uilabel(app.UIFigure);
            app.Number2EditFieldLabel.Text = 'Number2';
            app.Number2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Number2EditField.ValueChangedFcn = createCallbackFcn(app, @Number2EditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end